classdef image_formation_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        ekmEditFieldLabel           matlab.ui.control.Label
        ekmEditField                matlab.ui.control.NumericEditField
        DistancekmEditFieldLabel    matlab.ui.control.Label
        DistancekmEditField         matlab.ui.control.NumericEditField
        EyePositionmEditFieldLabel  matlab.ui.control.Label
        EyePositionmEditField       matlab.ui.control.NumericEditField
        Button                      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            imshow('object_0.jpg','Parent',app.UIAxes);
            e = app.ekmEditField.Value;
            xf = app.DistancekmEditField.Value;
            y0 = app.EyePositionmEditField.Value;
            im1 = imread('object_0.jpg');
            im1r = flip(im1,1);
            [w,l,h] = size(im1);
            full_image = [];

            global n;
            n = 150;   %对图片进行竖直方向n等分
            wi = ceil(w/n);
            %compute the trace
            x0 = 0;

            for i0 = 0:0.01:pi*3/4
                y10 = 1/tan(i0);
                %线性模型
                df = @(x,y)[y(2);(e/sin(i0))^2*y(1)-e/(sin(i0))^2];
                [x,y] = ode45(df,[x0,xf],[y0,y10]);
                %y(end,1)为最后一个y
                k = self_classify(y(end,1),w);
                k = fix(k);
                %判断成像位置
                if k == 1
                    partial_image = im1r(1:(wi*k),:,:);
                    full_image = [full_image;partial_image];
                elseif wi*k > w 
                    continue;
                elseif k == 0
                    continue;
                else        

                    partial_image = im1r((wi*(k-1)):(wi*k),:,:);
                    full_image = [full_image;partial_image];
                end
            end
            imshow(full_image,'Parent',app.UIAxes2);
            
            
            function out = self_classify(y,w)
                
                wii = w/(100*n);
                for j = 1:1000
                    if y >= wii*(j-1) && y <= wii*j
                        out = j;
                        return               
                    end
                    out = 0;                
                end
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Object')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Image')
            app.ekmEditFieldLabel = uilabel(app.UIFigure);
            app.ekmEditFieldLabel.Text = 'e[/km]';
            app.ekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancekmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancekmEditFieldLabel.Text = 'Distance[km]';
            app.DistancekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EyePositionmEditFieldLabel = uilabel(app.UIFigure);
            app.EyePositionmEditFieldLabel.Text = 'Eye Position[m]';
            app.EyePositionmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = image_formation_app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end